classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        ZoomButton                    matlab.ui.control.Button
        CakismakarsilikligecisButton  matlab.ui.control.Button
        CakismaaynihucreButton        matlab.ui.control.Button
        CakismasizcokrobotButton      matlab.ui.control.Button
        RobotSayisiSpinner            matlab.ui.control.Spinner
        RobotSayisiSpinnerLabel       matlab.ui.control.Label
        DropPlannerDropDownLabel      matlab.ui.control.Label
        SimulasyonubaslatButton       matlab.ui.control.Button
        DropPlannerDropDown           matlab.ui.control.DropDown
        RobotlarYerletirButton        matlab.ui.control.Button
        DarKoridorButton              matlab.ui.control.Button
        RandomMapButton               matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       mapSize = [50 50]
       map
       robots      % Bu satırı ekleyin
      numRobots double = 10  % Robot sayısı (istediğiniz değeri verin)
      paths   
      lastSeed double = NaN
      scenarioName char = ''
      currentPlannerType char = 'A*'  % ✅ EKLE
      currentSimTime double = 0  % ✅ EKLE
      zoomEnabled logical = false
      robotColors  
      deadlockCount double = 0
      deadlockTimes double = []
      totalWaitTime double = 0
    end


    methods (Access = public)
        
        function h = heuristic(~, node, goal)
        h = hypot(goal(1) - node(1), goal(2) - node(2));
        end


          function path = reconstructPath(~, cameFrom, current, startGrid, map)
            pathGrid = current;
            
            while ~(current(1) == startGrid(1) && current(2) == startGrid(2))
                parent = squeeze(cameFrom(current(2), current(1), :))';
                if all(parent == 0)
                    break;
                end
                current = parent;
                pathGrid = [current; pathGrid];
            end
            
            % Grid → World koordinatları
            path = zeros(size(pathGrid));
            for i = 1:size(pathGrid,1)
                path(i,:) = grid2world(map, pathGrid(i,:));
            end
          end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RandomMapButton
        function RandomMapButtonPushed(app, event)
         
            % Kullanıcıya mod seçtir
            choice = uiconfirm(app.UIFigure, ...
                'Hangi harita modunu kullanmak istersiniz?', ...
                'Harita Modu Seçimi', ...
                'Options', {'Mod-1: Karmaşık (Mevcut)', 'Mod-2: Labirent/Koridor', 'İptal'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 3);
            
            if strcmp(choice, 'İptal')
                return;
            end
            
            % Seed kayıt
            app.lastSeed = randi(1e6);
            rng(app.lastSeed);
            
            if contains(choice, 'Mod-1')
                app.scenarioName = 'Rastgele Harita – Karmaşık (Mod-1)';
                app.map = app.generateRandomMapMode1();
            else
                app.scenarioName = 'Rastgele Harita – Labirent (Mod-2)';
                app.map = app.generateRandomMapMode2();
            end
            
            % Görselleştir
            show(app.map, 'Parent', app.UIAxes);
            xlim(app.UIAxes, [0 app.mapSize(1)])
            ylim(app.UIAxes, [0 app.mapSize(2)])
            axis(app.UIAxes, 'equal')
            title(app.UIAxes, app.scenarioName);
            drawnow;

             % Harita geçerliliğini kontrol et
            if ~app.hasAtLeastOneValidPath(app.map)
                uialert(app.UIFigure, ...
                    'Harita geçersiz (yol bulunamıyor). Lütfen tekrar oluşturun.', ...
                    'Uyarı', 'Icon', 'warning');
                return;
            end
            
            fprintf('✅ Harita kalite kontrolünden geçti\n');

        end

        % Button pushed function: DarKoridorButton
        function DarKoridorButtonPushed(app, event)

            % === SEED KAYDI ===
            app.lastSeed = randi(1e6);
            rng(app.lastSeed);
            app.scenarioName = 'Dar Koridor – Deadlock (Olasılıksal)';
        
            % Boş harita
            map = binaryOccupancyMap(app.mapSize(1), app.mapSize(2));
            w = app.mapSize(1);
            h = app.mapSize(2);
        
            % =====================================
            % 1️⃣ KORİDOR PARAMETRELERİ (RASTGELE)
            % =====================================
            leftX  = randi([14 22]);      % sol duvar başlangıcı
            gapW   = randi([5 8]);        % koridor genişliği
            wallTh = randi([1 2]);        % duvar kalınlığı
        
            rightX = leftX + gapW;
        
            % Geçit (dar kapı)
            gateY = randi([15 35]);       % geçit yüksekliği
            gateH = randi([2 4]);         % geçit kalınlığı
        
            % =====================================
            % 2️⃣ DUVARLARI OLUŞTUR
            % =====================================
            % Sol duvar
            [X, Y] = meshgrid(leftX:leftX+wallTh-1, 1:h);
            setOccupancy(map, [X(:), Y(:)], 1);
        
            % Sağ duvar
            [X, Y] = meshgrid(rightX:rightX+wallTh-1, 1:h);
            setOccupancy(map, [X(:), Y(:)], 1);
        
            % =====================================
            % 3️⃣ GEÇİT AÇ
            % =====================================
            [X, Y] = meshgrid(leftX:rightX+wallTh-1, gateY:gateY+gateH-1);
            setOccupancy(map, [X(:), Y(:)], 0);
        
            % =====================================
            % 4️⃣ KORİDOR İÇİNE KÜÇÜK ENGELLER EKLE
            % =====================================
            numInnerObs = randi([4 8]);   % az sayıda, zorlaştırıcı
        
            for k = 1:numInnerObs
                if rand < 0.6
                    % Küçük dikdörtgen
                    bw = randi([2 3]);
                    bh = randi([2 3]);
                    xmin = leftX + 2;
                    xmax = rightX - 2;
                    
                    if xmin > xmax
                        continue;
                    end
                    
                    x = randi([xmin xmax]);
     
                    ymax = h - bh;
                    if ymax < 1
                        continue;
                    end
                    y = randi([1 ymax]);

                    [X,Y] = meshgrid(x:x+bw-1, y:y+bh-1);
                    setOccupancy(map, [X(:), Y(:)], 1);
                else
                    % İnce yatay duvar
                    len = randi([3 6]);
                    xmin = leftX + 2;
                    xmax = rightX - len - 2;
                    
                    if xmin > xmax
                        continue;
                    end
                    
                    x = randi([xmin xmax]);

                    y = randi(h);
                    [X,Y] = meshgrid(x:x+len-1, y);
                    setOccupancy(map, [X(:), Y(:)], 1);
                end
            end
        
            % =====================================
            % 5️⃣ GÖRSELLEŞTİR
            % =====================================
            show(map, 'Parent', app.UIAxes);
            axis(app.UIAxes, 'equal');
            axis(app.UIAxes, 'tight');
        
            app.map = map;
            
        end

        % Button pushed function: RobotlarYerletirButton
        function RobotlarYerletirButtonPushed(app, event)

              % Robot sayısını al
            app.numRobots = round(app.RobotSayisiSpinner.Value);
            
            if app.numRobots < 1
                uialert(app.UIFigure, 'Robot sayısı en az 1 olmalıdır', 'Hata');
                return;
            end
        
            % Harita kontrolü
            if isempty(app.map) || ~isa(app.map, 'binaryOccupancyMap')
                uialert(app.UIFigure, 'Önce bir harita oluşturun!', 'Hata');
                return;
            end
            
            map = app.map;
            gridSize = map.GridSize;
            
            % Tüm boş grid noktalarını topla
            freePoints = [];
            for x = 1:gridSize(1)
                for y = 1:gridSize(2)
                    worldPos = grid2world(map, [x, y]);
                    if checkOccupancy(map, worldPos) < 0.5
                        freePoints = [freePoints; worldPos];
                    end
                end
            end

            numFree = size(freePoints,1);
            if numFree < 2
                uialert(app.UIFigure, ...
                    'Robot yerleştirmek için yeterli boş hücre yok!', ...
                    'Hata');
                return;
            end
            
            if isempty(freePoints)
                uialert(app.UIFigure, 'Haritada boş alan yok!', 'Hata');
                return;
            end
            
            app.robots = struct([]);
            
            for i = 1:app.numRobots

                % Başlangıç noktası
                startIdx = randi(numFree);
                startPos = freePoints(startIdx, :);
                
                % ✅ Mesafe sınıfı seç (harita çapı referans)
                mapDiagonal = hypot(app.mapSize(1), app.mapSize(2));
                
                if mod(i, 3) == 1
                    % Yakın hedef: %10-%20
                    dmin = 0.10 * mapDiagonal;
                    dmax = 0.20 * mapDiagonal;
                elseif mod(i, 3) == 2
                    % Orta hedef: %30-%50
                    dmin = 0.30 * mapDiagonal;
                    dmax = 0.50 * mapDiagonal;
                else
                    % Uzak hedef: %60+
                    dmin = 0.60 * mapDiagonal;
                    dmax = inf;
                end
                
                % ✅ EN KISA YOL UZUNLUĞU İLE HEDEF SEÇ
                found = false;
                attempts = 0;
                maxAttempts = 500;
                
                while ~found && attempts < maxAttempts
                    goalIdx = randi(numFree);
                    goalPos = freePoints(goalIdx, :);
                    
                    % A* ile gerçek yol uzunluğunu hesapla
                    testPath = app.aStarPlanner(map, startPos, goalPos);
                    
                    if ~isempty(testPath)
                        pathLength = 0;
                        for p = 1:size(testPath,1)-1
                            pathLength = pathLength + norm(testPath(p+1,:) - testPath(p,:));
                        end
                        
                        if pathLength >= dmin && pathLength <= dmax
                            found = true;
                            fprintf('   R%d: Mesafe=%.1f (%.0f%% harita çapı)\n', ...
                                i, pathLength, (pathLength/mapDiagonal)*100);
                        end
                    end
                    
                    attempts = attempts + 1;
                end
                
                if ~found
                    warning('Robot %d için uygun hedef bulunamadı, en uzak nokta seçiliyor', i);
                    distances = sqrt(sum((freePoints - startPos).^2, 2));
                    [~, maxIdx] = max(distances);
                    goalPos = freePoints(maxIdx, :);
                end
                
                % Kaydet
                app.robots(i).start = startPos;
                app.robots(i).goal  = goalPos;
        
                % Geçerlilik kontrolü
                if checkOccupancy(map, startPos) > 0.5 || checkOccupancy(map, goalPos) > 0.5
                    error('Robot %d için start veya goal engel içinde!', i);
                end
        
                % Robot durumunu başlat
                app.robots(i).currentPos = startPos;
                app.robots(i).path = [];
                app.robots(i).pathIndex = 1;
                app.robots(i).finished = false;
                app.robots(i).trajectory = startPos;
                app.robots(i).theta = atan2(goalPos(2)-startPos(2), goalPos(1)-startPos(1));
                app.robots(i).waitingFor = [];
                app.robots(i).stuckCount = 0;
                
                % Hız ve tekerlek arraylerini başlat
                app.robots(i).velLinear  = zeros(0,1);
                app.robots(i).velAngular = zeros(0,1);
                app.robots(i).wheelL     = zeros(0,1);
                app.robots(i).wheelR     = zeros(0,1);
            end
            
            % Görselleştir
            show(map, 'Parent', app.UIAxes);
            hold(app.UIAxes, 'on')
            
            for i = 1:length(app.robots)
                plot(app.UIAxes, app.robots(i).start(1), app.robots(i).start(2), ...
                    'o', 'MarkerSize', 4, 'LineWidth', 1, ...
                    'MarkerEdgeColor', 'g', 'MarkerFaceColor', 'g')
                
                plot(app.UIAxes, app.robots(i).goal(1), app.robots(i).goal(2), ...
                    'x', 'MarkerSize', 5, 'LineWidth', 1.5, 'Color', 'r')
                
                text(app.UIAxes, ...
                    app.robots(i).start(1) + 0.3, ...
                    app.robots(i).start(2) + 0.3, ...
                    sprintf('%d', i), ...
                    'Color', 'w', 'FontSize', 6, 'FontWeight', 'bold');
            end
            
            hold(app.UIAxes, 'off')
       
            axis(app.UIAxes, 'equal');
            xlim(app.UIAxes, [0 app.mapSize(1)])
            ylim(app.UIAxes, [0 app.mapSize(2)])
            
            title(app.UIAxes, sprintf('%s | t=%.1fs | %d/%d başarılı', ...
                app.currentPlannerType, app.currentSimTime, sum([app.robots.finished]), app.numRobots));
        
            drawnow;


        end

        % Button pushed function: SimulasyonubaslatButton
        function SimulasyonubaslatButtonPushed(app, event)
            if isempty(app.map) || isempty(app.robots)
                uialert(app.UIFigure, 'Önce harita oluşturun ve robotları yerleştirin!', 'Hata');
                return;
            end
        
            plannerType = app.DropPlannerDropDown.Value;
            app.currentPlannerType = plannerType;  % ✅ KAYDET
            % ✅ RENKLERİ PROPERTY OLARAK KAYDET
            app.robotColors = lines(app.numRobots);

            baseMap = app.map;
            inflatedMap = baseMap;  % Inflation yapma
        
            % -------------------------
            % İLK PLANLAMA + INIT
            % -------------------------
            fprintf('\n=== İLK PLANLAMA BAŞLIYOR ===\n');
            app.paths = {};  % Yolları saklamak için
            
            for i = 1:app.numRobots
                start = app.robots(i).start;
                goal  = app.robots(i).goal;
                
                fprintf('Robot %d: Start=[%.2f, %.2f], Goal=[%.2f, %.2f]\n', ...
                    i, start(1), start(2), goal(1), goal(2));
                
                % ✅ YENİ: Theta* desteği
                if strcmp(plannerType,'Theta*')
                    path = app.thetaStarPlanner(inflatedMap, start, goal);
                elseif strcmp(plannerType,'A*')
                    path = app.aStarPlanner(inflatedMap, start, goal);
                else
                    path = app.prmPlanner(inflatedMap, start, goal);
                end
        
                if isempty(path)
                    fprintf('  ❌ Robot %d için YOL BULUNAMADI!\n', i);
                    app.robots(i).path = [start; goal];
                    app.paths{i} = [start; goal];
                else
                    fprintf('  ✅ Robot %d için yol bulundu: %d waypoint\n', i, size(path, 1));
                    app.robots(i).path = path;
                    app.paths{i} = path;
                end
        
                app.robots(i).pathIndex   = 1;
                app.robots(i).currentPos  = start;
                app.robots(i).trajectory  = start;
                app.robots(i).finished    = false;
                app.robots(i).waitingFor  = [];
                app.robots(i).stuckCount  = 0;


            end
        
            % -------------------------
            % İLK YOLLARI GÖRSELLEŞTİR
            % -------------------------
            cla(app.UIAxes)
            show(baseMap, 'Parent', app.UIAxes);
            hold(app.UIAxes, 'on')
            axis(app.UIAxes, 'equal')
            axis(app.UIAxes, 'tight')
            
            % Planlanan yolları çiz
            app.robotColors = lines(app.numRobots);
            for i = 1:app.numRobots
                if ~isempty(app.paths{i}) && size(app.paths{i}, 1) > 1
                    plot(app.UIAxes, app.paths{i}(:,1), app.paths{i}(:,2), ...
                        '--', 'LineWidth', 2, 'Color', app.robotColors(i,:), 'LineStyle', ':');
                end
                
                % Start ve Goal
                plot(app.UIAxes, app.robots(i).start(1), app.robots(i).start(2), ...
                    'o', 'MarkerSize', 10, 'LineWidth', 2, ...
                    'MarkerEdgeColor', 'g', 'MarkerFaceColor', 'g');
                plot(app.UIAxes, app.robots(i).goal(1), app.robots(i).goal(2), ...
                    'x', 'MarkerSize', 10, 'LineWidth', 2.5, 'Color', 'r');
                
                text(app.UIAxes, app.robots(i).start(1)+1, app.robots(i).start(2)+1, ...
                    sprintf('%d', i), 'Color', 'white', 'FontSize', 9, 'FontWeight', 'bold');
            end
            
            successCount = sum(cellfun(@(x) ~isempty(x) && size(x,1) > 1, app.paths));
            title(app.UIAxes, sprintf('%s: %d/%d Robot - Planlanan Yollar', ...
                plannerType, successCount, app.numRobots));
            
            drawnow;
            pause(2);  % Kullanıcı yolları görebilsin
        
            % -------------------------
            % SİMÜLASYON PARAMETRELERİ
            % -------------------------
            dt = 0.1;
            simTime = 0;
            app.currentSimTime = 0;  % ✅ BAŞLAT
            maxTime = 200;
            
            speed = 8.0;
            stepDist = speed * dt;
            
            GOAL_THRESH = 1.5;
            
            % ✅ Planner'a göre waypoint threshold
            if strcmp(plannerType,'PRM')
                WP_THRESH = 2.0;
                LOOKAHEAD = 8;
                COLLISION_RADIUS = 1.0;
            elseif strcmp(plannerType,'A*')
                WP_THRESH = 1.0;   % ✅ A* için daha sıkı takip
                LOOKAHEAD = 3;     % ✅ Kısa ama kesin adımlar
                COLLISION_RADIUS = 1.2;  % ✅ Biraz daha geniş mesafe
            else  % Theta*
                WP_THRESH = 1.5;
                LOOKAHEAD = 6;
                COLLISION_RADIUS = 1.0;
            end
            
            fprintf('\n=== SİMÜLASYON BAŞLIYOR ===\n');
            
            frameCount = 0;
            while simTime < maxTime
            
                if ~isvalid(app) || ~isvalid(app.UIFigure)
                    return;
                end
            
                frameCount = frameCount + 1;
            
                 if mod(frameCount, 100) == 0
                    fprintf('\n[t=%.1f] Durum: %d/%d robot finished\n', ...
                        simTime, sum([app.robots.finished]), app.numRobots);
                end
            
                for k = 1:app.numRobots
                    app.robots(k).waitingFor = [];
                end
            
                allFinished = true;
            
                for i = 1:app.numRobots
            
                    if app.robots(i).finished
                        continue;
                    end
                    allFinished = false;
            
                    curPos  = app.robots(i).currentPos;
                    goalPos = app.robots(i).goal;
            
                   if norm(goalPos - curPos) < GOAL_THRESH
                        app.robots(i).finished = true;
                        fprintf('[t=%.1f] ✅ Robot %d hedefe ulaştı!\n', simTime, i);
                        
                        % ✅ ANLIK GRAFİK ÇİZ
                        app.plotRobotVelocities(i, dt);
                        
                        continue;
                   end
                                
                    path = app.robots(i).path;
                    idx  = app.robots(i).pathIndex;
            
                    if isempty(path)
                        nextWp = goalPos;
                    else
                        % ✅ Planner'a göre look-ahead
                        lookAhead = min(idx + LOOKAHEAD, size(path, 1));
                        nextWp = path(lookAhead, :);
            
                        if idx <= size(path,1)
                            if norm(path(idx,:) - curPos) < WP_THRESH
                                app.robots(i).pathIndex = min(idx + 1, size(path,1));
                               
                            end
                        end
                    end


                    path = app.robots(i).path;
                    idx  = app.robots(i).pathIndex;
                    
                    if isempty(path) || idx > size(path,1)
                        nextWp = goalPos;
                    else
                        lookAhead = min(idx + LOOKAHEAD, size(path, 1));
                        nextWp = path(lookAhead, :);
                    end
            
                    dir = nextWp - curPos;
                    d = norm(dir);
                    if d < 1e-6
                        continue;
                    end
                    dir = dir / d;
            
                    desiredPos = curPos + dir * min(stepDist, d);
            
                    % Robot-robot çarpışma

                    blockedBy = [];
                    COLLISION_RADIUS = 1.0;  % ✅ 0.8'den 1.5'e çıkarıldı
                    
                    for j = 1:app.numRobots
                        if j ~= i && ~app.robots(j).finished
                            if norm(desiredPos - app.robots(j).currentPos) < COLLISION_RADIUS
                                blockedBy(end+1) = j;  %#ok<AGROW>
                            end
                        end
                    end
                    
                    if ~isempty(blockedBy)
                        app.robots(i).waitingFor = unique(blockedBy);
                        
                        % ✅ YENİ: Önce 360° tarama yap
                        [success, avoidPos] = app.localAvoidStep360( ...
                            baseMap, curPos, goalPos, stepDist * 0.8, app.robots, i);
                        
                        if success
                            % Yeni pozisyon başka robotlarla çakışıyor mu?
                            canMove = true;
                            for j = blockedBy
                                if norm(avoidPos - app.robots(j).currentPos) < COLLISION_RADIUS
                                    canMove = false;
                                    break;
                                end
                            end
                            
                            if canMove
                                app.robots(i).currentPos = avoidPos;
                                app.robots(i).trajectory(end+1,:) = avoidPos;
                                app.robots(i).waitingFor = [];
                                app.robots(i).stuckCount = 0;
                            end
                       
                        end
                        
                        % ✅ YENİ: Çok takılırsa zorunlu küçük hareket
                        % ✅ Çok takılırsa yeniden planlama
                        if app.robots(i).stuckCount > 40
                            fprintf('[t=%.1f] 🔄 Robot %d çok bekliyor, yeniden planlıyor\n', simTime, i);
                            
                            % Planner'a göre yeniden planla
                            if strcmp(plannerType,'Theta*')
                                newPath = app.thetaStarPlanner(baseMap, curPos, goalPos);
                            elseif strcmp(plannerType,'A*')
                                newPath = app.aStarPlanner(baseMap, curPos, goalPos);
                            else
                                newPath = app.prmPlanner(baseMap, curPos, goalPos);
                            end
                            
                            if ~isempty(newPath)
                                app.robots(i).path = newPath;
                                app.robots(i).pathIndex = 1;
                                app.robots(i).stuckCount = 0;
                                fprintf('   ✅ Yeni yol bulundu (%d waypoints)\n', size(newPath,1));
                            else
                                % Son çare: Küçük rastgele hareket
                                randomAngle = rand * 2 * pi;
                                smallStep = [cos(randomAngle), sin(randomAngle)] * (stepDist * 0.5);
                                tryPos = curPos + smallStep;
                                
                                if checkOccupancy(baseMap, tryPos) < 0.5
                                    app.robots(i).currentPos = tryPos;
                                    app.robots(i).trajectory(end+1,:) = tryPos;
                                    app.robots(i).stuckCount = 0;
                                end
                            end
                        end
                        
                        continue;
                    end
            
                    % Engel kontrolü
                    collision = checkOccupancy(baseMap, desiredPos) > 0.5;
            
                    if collision
                        [success, avoidPos] = app.localAvoidStepGoalBiased( ...
                            baseMap, curPos, goalPos, stepDist);
                    
                        if success
                            app.robots(i).currentPos = avoidPos;
                            app.robots(i).trajectory(end+1,:) = avoidPos;
                            app.robots(i).stuckCount = 0;
                        else
                            app.robots(i).stuckCount = app.robots(i).stuckCount + 1;
                            
                            % ✅ A* için erken yeniden planlama
                            if strcmp(plannerType,'A*') && app.robots(i).stuckCount > 30
                                fprintf('[t=%.1f] 🔄 Robot %d (A*) yeniden planlıyor\n', simTime, i);
                                
                                % Yeni A* yolu hesapla
                                newPath = app.aStarPlanner(baseMap, curPos, goalPos);
                                if ~isempty(newPath)
                                    app.robots(i).path = newPath;
                                    app.robots(i).pathIndex = 1;
                                    app.robots(i).stuckCount = 0;
                                end
                            elseif app.robots(i).stuckCount > 80
                                fprintf('[t=%.1f] 🔄 Robot %d yeniden planlıyor\n', simTime, i);
                                app.replanPath(i);
                            end
                        end
                        continue;
                    end
            
                    % Hareketi kabul et
                    app.robots(i).currentPos = desiredPos;
                    app.robots(i).trajectory(end+1,:) = desiredPos;
                    app.robots(i).stuckCount = 0;

                    % ===============================
                    % HIZ VE TEKERLEK HESABI
                    % ===============================
                    prevPos = curPos;
                    newPos  = desiredPos;
                    
                    % Doğrusal hız
                    v = norm(newPos - prevPos) / dt;
                    
                    % Yön açısı
                    theta_new = atan2(newPos(2)-prevPos(2), newPos(1)-prevPos(1));
                    w = (theta_new - app.robots(i).theta) / dt;
                    
                    app.robots(i).theta = theta_new;
                    
                    % Diferansiyel sürüş
                    r = 0.1;   % teker yarıçapı
                    L = 0.5;   % aks mesafesi
                    
                    omegaL = (v - w*L/2) / r;
                    omegaR = (v + w*L/2) / r;
                    
                    % KAYDET
                    app.robots(i).velLinear(end+1,1)  = v;
                    app.robots(i).velAngular(end+1,1) = w;
                    app.robots(i).wheelL(end+1,1)     = omegaL;
                    app.robots(i).wheelR(end+1,1)     = omegaR;


                end
            
                % ✅ GÖRSELLEŞTİRME (DÜZELTILMIŞ)
                if mod(frameCount, 2) == 0
                    app.currentSimTime = simTime;  % ✅ GÜNCELLE
                    cla(app.UIAxes)
                    show(baseMap, 'Parent', app.UIAxes);
                    hold(app.UIAxes, 'on')
            
                    for i = 1:app.numRobots
                        traj = app.robots(i).trajectory;
                        if size(traj,1) > 1
                            plot(app.UIAxes, traj(:,1), traj(:,2), ...
                                'LineWidth', 2, 'Color', app.robotColors(i,:));
                        end
            
                        if ~app.robots(i).finished
                            plot(app.UIAxes, app.robots(i).currentPos(1), ...
                                app.robots(i).currentPos(2), ...
                                'o', 'MarkerSize', 10, 'MarkerFaceColor', app.robotColors(i,:), ...
                                'MarkerEdgeColor', 'k', 'LineWidth', 1.5);
                        else
                            plot(app.UIAxes, app.robots(i).currentPos(1), ...
                                app.robots(i).currentPos(2), ...
                                's', 'MarkerSize', 8, 'MarkerFaceColor', [0.5 0.5 0.5], ...
                                'MarkerEdgeColor', 'k', 'LineWidth', 1);
                        end
            
                        plot(app.UIAxes, app.robots(i).goal(1), ...
                            app.robots(i).goal(2), ...
                            'x', 'MarkerSize', 10, 'LineWidth', 2.5, 'Color', 'r');
            
                        text(app.UIAxes, app.robots(i).currentPos(1)+0.8, ...
                            app.robots(i).currentPos(2)+0.8, sprintf('%d', i), ...
                            'Color', 'w', 'FontWeight', 'bold', 'FontSize', 9);
                    end
            
                    % ✅ LEGEND DÖNGÜ DIŞINDA
                    legend(app.UIAxes, ...
                        {'Trajectory','Robot','Goal','Wait-For'}, ...
                        'TextColor','w', ...
                        'Location','northoutside', ...
                        'Orientation','horizontal');
            
                    % Wait-for okları
                    for i = 1:app.numRobots
                        if ~isempty(app.robots(i).waitingFor)
                            for j = app.robots(i).waitingFor
                                if j >= 1 && j <= app.numRobots
                                    p1 = app.robots(i).currentPos;
                                    p2 = app.robots(j).currentPos;
                                    quiver(app.UIAxes, p1(1), p1(2), ...
                                        p2(1)-p1(1), p2(2)-p1(2), 0, ...
                                        'Color', 'r', 'LineWidth', 1.5, 'MaxHeadSize', 0.4);
                                end
                            end
                        end
                    end
            
                    axis(app.UIAxes, 'equal');
                    xlim(app.UIAxes, [0 app.mapSize(1)])
                    ylim(app.UIAxes, [0 app.mapSize(2)])
                    
                    % ✅ TITLE HER ZAMAN GÖSTER
                    title(app.UIAxes, sprintf('%s | t=%.1fs | %d/%d başarılı', ...
                        app.currentPlannerType, app.currentSimTime, sum([app.robots.finished]), app.numRobots));
            
                    drawnow;

                    % Deadlock tespit edildiğinde başlığı güncelleyin:

                    if app.detectDeadlock()
                        title(app.UIAxes, sprintf('%s | t=%.1fs | %d/%d başarılı | ⚠️ DEADLOCK!', ...
                            app.currentPlannerType, app.currentSimTime, sum([app.robots.finished]), app.numRobots), ...
                            'Color', 'r', 'FontSize', 12, 'FontWeight', 'bold');
                        
                        app.deadlockCount = app.deadlockCount + 1;
                        app.deadlockTimes(end+1) = simTime;
                        
                        drawnow;
                    end


                end
               % ========================================
                % DEADLOCK TESPİTİ VE ÇÖZÜMÜ
                % ========================================
                if mod(frameCount, 50) == 0  % Her 5 saniyede bir kontrol et
                    if app.detectDeadlock()
                        activeRobots = find(~[app.robots.finished]);
                        app.resolveDeadlock(activeRobots);
                        
                        % Kısa pause (kullanıcı görebilsin)
                        pause(1);
                    end
                end
            
                pause(dt);
                simTime = simTime + dt;
            
                % SimulasyonubaslatButton içinde, simülasyon bitince ekleyin:
                if allFinished
                    fprintf('\n✅ Simülasyon tamamlandı (%.1f saniye)\n', simTime);
                    
                    % ✅ Başarı mesajı göster
                    successRate = sum([app.robots.finished]) / app.numRobots * 100;
                    msgText = sprintf('Senaryo: %s\nBaşarı: %d/%d (%.0f%%)\nSüre: %.1fs', ...
                        app.scenarioName, sum([app.robots.finished]), app.numRobots, successRate, simTime);
                    
                    uialert(app.UIFigure, msgText, 'Simülasyon Tamamlandı', 'Icon', 'success');
                    break;
                end
            end
        
            hold(app.UIAxes, 'off');
            
            fprintf('\n=== SONUÇ ===\n');
            fprintf('Toplam süre: %.1f saniye\n', simTime);
            fprintf('Hedefe ulaşan: %d/%d robot\n', sum([app.robots.finished]), app.numRobots);


            fprintf('\n=== DEADLOCK ANALİZİ ===\n');
            fprintf('Toplam deadlock: %d kez\n', app.deadlockCount);
            if app.deadlockCount > 0
                fprintf('Deadlock zamanları: ');
                fprintf('%.1fs ', app.deadlockTimes);
                fprintf('\n');
            end
            fprintf('Toplam bekleme süresi: %.1fs\n', app.totalWaitTime);

  
                end
            end 
 
methods (Access = private)


    %==============================================================
    % HARİTA KALİTE KONTROLÜ: En az bir geçerli yol var mı?
    %==============================================================
    function ok = hasAtLeastOneValidPath(app, map)
        ok = false;
    
        % Haritadaki serbest hücreleri topla
        occ = getOccupancy(map);
        freeIdx = find(occ < 0.5);

    
        if numel(freeIdx) < 2
            return;
        end
    
        % Rastgele birkaç serbest nokta seç
        [rows, cols] = size(occ);
        [ys, xs] = ind2sub([rows, cols], freeIdx);
    
        points = [xs ys];
    
        numTests = min(10, size(points,1)); % 10 deneme yeter
        idx = randperm(size(points,1), numTests);
    
        for i = 1:numTests
            for j = i+1:numTests
                p1 = grid2world(map, points(idx(i),:));
                p2 = grid2world(map, points(idx(j),:));
    
                % A* ile yol dene
                path = app.aStarPlanner(map, p1, p2);
    
                if ~isempty(path)
                    ok = true;
                    return;
                end
            end
        end
    end


    %==============================================================
    % 0) ORTAK YARDIMCI: Hücreleri harita sınırına kırp
    %==============================================================
    function cells = clipCellsToMap(app, cells)
        if isempty(cells); return; end
        cells = round(cells);
        cells = unique(cells, 'rows');

        in = cells(:,1) >= 1 & cells(:,1) <= app.mapSize(1) & ...
             cells(:,2) >= 1 & cells(:,2) <= app.mapSize(2);

        cells = cells(in, :);
    end

    %==============================================================
    % 1) Hücre döndürme (L-shape için)
    %==============================================================
    function cells = rotateCells(app, cells, c, rot)
        %#ok<INUSD>  % app kullanılmasa bile method signature doğru kalsın
        cx = c(1); cy = c(2);

        x = cells(:,1) - cx;
        y = cells(:,2) - cy;

        % rot: 0,1,2,3 => 0,90,180,270
        for k = 1:rot
            tmp = x;
            x = -y;
            y = tmp;
        end

        cells(:,1) = round(x + cx);
        cells(:,2) = round(y + cy);
    end

    %==============================================================
    % 2) U-shape engel
    %==============================================================
    function map = addUShape(app, map, x, y, w, h, t)
        % U-şekilli engel: 3 duvar
        [X1, Y1] = meshgrid(x:x+t-1,     y:y+h-1);     % sol dikey
        [X2, Y2] = meshgrid(x:x+w-1,     y:y+t-1);     % alt yatay
        [X3, Y3] = meshgrid(x+w-t:x+w-1, y:y+h-1);     % sağ dikey

        cells = [X1(:) Y1(:); X2(:) Y2(:); X3(:) Y3(:)];
        cells = app.clipCellsToMap(cells);

        if ~isempty(cells)
            setOccupancy(map, cells, 1);
        end
    end

    %==============================================================
    % 3) L-shape engel
    %==============================================================
    function map = addLShape(app, map, x, y, w, h, t, rot)
        [X1, Y1] = meshgrid(x:x+w-1, y:y+t-1);   % yatay kol
        [X2, Y2] = meshgrid(x:x+t-1, y:y+h-1);   % dikey kol
        cells = [X1(:) Y1(:); X2(:) Y2(:)];

        cx = x + (w-1)/2;
        cy = y + (h-1)/2;

        cells = app.rotateCells(cells, [cx cy], rot);
        cells = app.clipCellsToMap(cells);

        if ~isempty(cells)
            setOccupancy(map, cells, 1);
        end
    end

    %==============================================================
    % 4) Elips engel
    %==============================================================
    function map = addEllipse(app, map, cx, cy, a, b, rotDeg)
        theta = deg2rad(rotDeg);

        [X, Y] = meshgrid(1:app.mapSize(1), 1:app.mapSize(2));

        xr = (X - cx)*cos(theta) + (Y - cy)*sin(theta);
        yr = -(X - cx)*sin(theta) + (Y - cy)*cos(theta);

        mask = (xr.^2)/(a^2) + (yr.^2)/(b^2) <= 1;

        [yy, xx] = find(mask);
        cells = [xx yy];
        cells = app.clipCellsToMap(cells);

        if ~isempty(cells)
            setOccupancy(map, cells, 1);
        end
    end

    %==============================================================
    % 5) MOD-1: Karmaşık rastgele harita
    %==============================================================
    function map = generateRandomMapMode1(app)

        map = binaryOccupancyMap(app.mapSize(1), app.mapSize(2));
        targetFill = 0.20;

        while true
            occ = getOccupancy(map);
            currentFill = sum(occ(:) > 0) / numel(occ);
            if currentFill >= targetFill
                break;
            end

            r = rand;
            if r < 0.45
                shape = "rect";
            elseif r < 0.70
                shape = "wall";
            elseif r < 0.87
                shape = "L";
            else
                shape = "ellipse";
            end

            switch shape
                case "rect"
                    if rand < 0.75
                        w = randi([2 4]); h = randi([2 4]);
                    else
                        w = randi([6 10]); h = randi([6 10]);
                    end
                    xmax = app.mapSize(1) - w;
                    ymax = app.mapSize(2) - h;
                    
                    if xmax < 1 || ymax < 1
                        continue;
                    end
                    
                    x = randi([1 xmax]);
                    y = randi([1 ymax]);

                    [X,Y] = meshgrid(x:x+w-1, y:y+h-1);
                    setOccupancy(map, [X(:) Y(:)], 1);

                case "wall"
                    if rand < 0.75, len = randi([3 6]);
                    else,           len = randi([7 12]);
                    end

                    if rand < 0.5
                        x = randi([1 app.mapSize(1)-len]);
                        y = randi(app.mapSize(2));
                        [X,Y] = meshgrid(x:x+len-1, y);
                    else
                        x = randi(app.mapSize(1));
                        y = randi([1 app.mapSize(2)-len]);
                        [X,Y] = meshgrid(x, y:y+len-1);
                    end
                    setOccupancy(map, [X(:) Y(:)], 1);

                case "L"
                    if rand < 0.8
                        w = randi([4 8]);  h = randi([4 8]);  t = randi([1 2]);
                    else
                        w = randi([8 12]); h = randi([8 12]); t = randi([2 3]);
                    end
                    x = randi([1 app.mapSize(1)-w]);
                    y = randi([1 app.mapSize(2)-h]);
                    rot = randi([0 3]);
                    map = app.addLShape(map, x, y, w, h, t, rot);

                case "ellipse"
                    if rand < 0.8
                        a = randi([2 4]); b = randi([2 4]);
                    else
                        a = randi([4 7]); b = randi([4 7]);
                    end
                    cx = randi([1 app.mapSize(1)]);
                    cy = randi([1 app.mapSize(2)]);
                    rotDeg = randi([0 179]);
                    map = app.addEllipse(map, cx, cy, a, b, rotDeg);
            end
        end

        % Kalite kontrol (toolbox varsa daha iyi, yoksa da çalışır)
        map = app.removeIsolatedFreeRegions(map);
        map = app.removeShortDeadEnds(map, 0.85);
    end

    %==============================================================
    % 6) MOD-2: Koridor/labirent harita
    %==============================================================
    function map = generateRandomMapMode2(app)

        map = binaryOccupancyMap(app.mapSize(1), app.mapSize(2));

        numHorizontalWalls = randi([3 5]);
        numVerticalWalls   = randi([3 5]);

        % Yatay duvarlar
        for k = 1:numHorizontalWalls
            wallY = randi([5 app.mapSize(2)-5]);
            wallThickness = randi([1 2]);

            maxGap = app.mapSize(1) - 15;
            if maxGap < 5
                continue;
            end
            gapStart = randi([5 maxGap]);
            gapWidth = randi([4 8]);

            [X,Y] = meshgrid(1:gapStart-1, wallY:wallY+wallThickness-1);
            setOccupancy(map, [X(:), Y(:)], 1);

            [X,Y] = meshgrid(gapStart+gapWidth:app.mapSize(1), wallY:wallY+wallThickness-1);
            setOccupancy(map, [X(:), Y(:)], 1);
        end

        % Dikey duvarlar
        for k = 1:numVerticalWalls
            wallX = randi([5 app.mapSize(1)-5]);
            wallThickness = randi([1 2]);

            maxGap = app.mapSize(2) - 15;
            if maxGap < 5
                continue;
            end
            gapStart = randi([5 maxGap]);
            gapWidth = randi([4 8]);

            [X,Y] = meshgrid(wallX:wallX+wallThickness-1, 1:gapStart-1);
            setOccupancy(map, [X(:), Y(:)], 1);

            [X,Y] = meshgrid(wallX:wallX+wallThickness-1, gapStart+gapWidth:app.mapSize(2));
            setOccupancy(map, [X(:), Y(:)], 1);
        end

        % Ek engeller
        numExtraObs = randi([5 10]);
        for k = 1:numExtraObs
            if rand < 0.6
                w = randi([4 8]); h = randi([4 8]); t = randi([1 2]);
                x = randi([1 app.mapSize(1)-w]);
                y = randi([1 app.mapSize(2)-h]);
                map = app.addUShape(map, x, y, w, h, t);
            else
                w = randi([2 4]); h = randi([2 4]);
                x = randi([1 app.mapSize(1)-w]);
                y = randi([1 app.mapSize(2)-h]);
                [X,Y] = meshgrid(x:x+w-1, y:y+h-1);
                setOccupancy(map, [X(:) Y(:)], 1);
            end
        end

        map = app.removeIsolatedFreeRegions(map);
        map = app.removeShortDeadEnds(map, 0.80);
    end

    %==============================================================
    % 7) Dead-end temizleme (seninki aynı, küçük güvenlik)
    %==============================================================
    function map = removeShortDeadEnds(app, map, removeProb)
        %#ok<INUSD>
        occFree = getOccupancy(map) < 0.5; % free = 1
        [h,w] = size(occFree);

        for iter = 1:2
            toRemove = false(h,w);

            for y = 2:h-1
                for x = 2:w-1
                    if occFree(y,x)
                        neighbors = occFree(y-1:y+1, x-1:x+1);
                        count = sum(neighbors(:)) - 1;
                        if count <= 1 && rand < removeProb
                            toRemove(y,x) = true;
                        end
                    end
                end
            end

            occFree(toRemove) = false;
        end

        setOccupancy(map, ~occFree); % obstacle=1
    end

    %==============================================================
    % 8) İzole serbest bölgeleri temizle
    %   - Toolbox varsa bwconncomp
    %   - Toolbox yoksa kendi BFS connected components
    %==============================================================
    function map = removeIsolatedFreeRegions(app, map)
        %#ok<INUSD>

        occFree = getOccupancy(map) < 0.5; % free = 1

        % --- Toolbox varsa kullan
        if exist('bwconncomp','file') == 2
            CC = bwconncomp(occFree, 4);

            if CC.NumObjects <= 1
                return;
            end

            sizes = cellfun(@numel, CC.PixelIdxList);
            [~, mainIdx] = max(sizes);

            for i = 1:CC.NumObjects
                if i ~= mainIdx
                    idx = CC.PixelIdxList{i};
                    occFree(idx) = 0;
                end
            end

            setOccupancy(map, ~occFree);
            return;
        end

        % --- Toolbox yoksa: 4-komşu BFS ile en büyük bileşeni bul
        labels = zeros(size(occFree));
        label = 0;
        counts = [];

        [H,W] = size(occFree);
        for y = 1:H
            for x = 1:W
                if occFree(y,x) && labels(y,x)==0
                    label = label + 1;
                    q = [y x];
                    labels(y,x) = label;
                    c = 0;

                    while ~isempty(q)
                        p = q(1,:); q(1,:) = [];
                        c = c + 1;

                        nbrs = [p+[1 0]; p+[-1 0]; p+[0 1]; p+[0 -1]];
                        for k = 1:4
                            ny = nbrs(k,1); nx = nbrs(k,2);
                            if ny>=1 && ny<=H && nx>=1 && nx<=W
                                if occFree(ny,nx) && labels(ny,nx)==0
                                    labels(ny,nx) = label;
                                    q(end+1,:) = [ny nx]; %#ok<AGROW>
                                end
                            end
                        end
                    end

                    counts(label) = c; %#ok<AGROW>
                end
            end
        end

        if label <= 1
            return;
        end

        [~, mainLabel] = max(counts);
        occFree(labels ~= mainLabel) = 0;

        setOccupancy(map, ~occFree);
    end


    % ============================================
    % ANLIK GRAFİK ÇİZİMİ (Robot başarılı olunca)
    % ============================================
    function plotRobotVelocities(app, robotIdx, dt)
        % Tek bir robot için hız grafiği çizer
        
        i = robotIdx;
        
        % Veri kontrolü
        if isempty(app.robots(i).velLinear) || length(app.robots(i).velLinear) < 2
            fprintf('   ⚠️ Robot %d için yeterli veri yok\n', i);
            return;
        end
        
        % Zaman vektörü
        t = (0:length(app.robots(i).velLinear)-1) * dt;
        
        % Yeni figure oluştur
        figHandle = figure('Name', sprintf('Robot %d - Hız Analizi ✅', i), ...
            'Color', 'w', 'Position', [100 + i*50, 100 + i*50, 1200, 400]);
        
        % =============================
        % SOL PANEL: Doğrusal ve Açısal Hız
        % =============================
        subplot(1, 2, 1)
        
        plot(t, app.robots(i).velLinear, 'b-', 'LineWidth', 2.5); hold on
        plot(t, app.robots(i).velAngular, 'r-', 'LineWidth', 2.5);
        
        grid on
        set(gca, 'Color', [0.95 0.95 0.95], ...
                 'GridColor', 'w', ...
                 'GridAlpha', 0.6, ...
                 'FontSize', 11, ...
                 'LineWidth', 1.5);
        
        title(sprintf('Robot %d: Doğrusal ve Açısal Hız', i), ...
            'FontSize', 13, 'FontWeight', 'bold', 'Color', 'k')
        
        xlabel('Zaman (s)', 'FontSize', 12, 'FontWeight', 'bold')
        ylabel('Hız', 'FontSize', 12, 'FontWeight', 'bold')
        
        legend({'v (m/s)', 'ω (rad/s)'}, ...
            'Location', 'best', ...
            'FontSize', 11, ...
            'TextColor', 'k', ...
            'Box', 'on');
        
        allVels = [app.robots(i).velLinear; app.robots(i).velAngular];
        if ~isempty(allVels)
            ylim([min(allVels) - 0.5, max(allVels) + 0.5]);
        end
        
        % =============================
        % SAĞ PANEL: Tekerlek Hızları
        % =============================
        subplot(1, 2, 2)
        
        plot(t, app.robots(i).wheelL, 'Color', [0 0.7 0], 'LineWidth', 2.5); hold on
        plot(t, app.robots(i).wheelR, 'Color', [0.8 0.4 0], 'LineWidth', 2.5);
        
        grid on
        set(gca, 'Color', [0.95 0.95 0.95], ...
                 'GridColor', 'w', ...
                 'GridAlpha', 0.6, ...
                 'FontSize', 11, ...
                 'LineWidth', 1.5);
        
        title(sprintf('Robot %d: Tekerlek Hızları', i), ...
            'FontSize', 13, 'FontWeight', 'bold', 'Color', 'k')
        
        xlabel('Zaman (s)', 'FontSize', 12, 'FontWeight', 'bold')
        ylabel('Tekerlek Hızı (rad/s)', 'FontSize', 12, 'FontWeight', 'bold')
        
        legend({'Sol Tekerlek', 'Sağ Tekerlek'}, ...
            'Location', 'best', ...
            'FontSize', 11, ...
            'TextColor', 'k', ...
            'Box', 'on');
        
        allWheels = [app.robots(i).wheelL; app.robots(i).wheelR];
        if ~isempty(allWheels)
            ylim([min(allWheels) - 0.5, max(allWheels) + 0.5]);
        end
        
        % Genel başlık
        sgtitle(sprintf('Robot %d - %s Planner - Başarıyla Tamamlandı! ✅', ...
            i, app.currentPlannerType), ...
            'FontSize', 14, 'FontWeight', 'bold', 'Color', [0 0.6 0]);
        
        fprintf('   📊 Robot %d için grafik oluşturuldu\n', i);
    end

    % methods (Access = private) bloğuna ekleyin:
    
    function isDeadlock = detectDeadlock(app)
        % TÜM SENARYOLAR İÇİN DEADLOCK TESPİTİ
        
        isDeadlock = false;
        
        % Aktif robotları bul
        activeRobots = find(~[app.robots.finished]);
        
        if length(activeRobots) < 2
            return;  % Tek robot varsa deadlock olamaz
        end
        
        % ============================================
        % TİP 1: DAİRESEL BEKLEME (Circular Wait)
        % ============================================
        allWaiting = true;
        for i = activeRobots
            if isempty(app.robots(i).waitingFor)
                allWaiting = false;
                break;
            end
        end
        
        if allWaiting
            % Döngü arama
            for i = activeRobots
                if app.findWaitCycle(i, activeRobots)
                    isDeadlock = true;
                    fprintf('\n🚨 DEADLOCK TESPİT EDİLDİ (Tip 1: Circular Wait)\n');
                    return;
                end
            end
        end
        
        % ============================================
        % TİP 2: UZUN SÜRELİ TAKILMA (Long Stuck)
        % ============================================
        stuckThreshold = 50;  % 5 saniye (dt=0.1)
        numStuck = 0;
        
        for i = activeRobots
            if app.robots(i).stuckCount > stuckThreshold
                numStuck = numStuck + 1;
            end
        end
        
        if numStuck >= 2
            isDeadlock = true;
            fprintf('\n🚨 DEADLOCK TESPİT EDİLDİ (Tip 2: Multiple Long Stuck - %d robots)\n', numStuck);
            return;
        end
        
        % ============================================
        % TİP 3: YAKIN MESAFE + TAKILMA (Spatial Deadlock)
        % ============================================
        PROXIMITY_THRESH = 2.0;
        
        for i = 1:length(activeRobots)
            for j = i+1:length(activeRobots)
                idx1 = activeRobots(i);
                idx2 = activeRobots(j);
                
                dist = hypot(app.robots(idx1).currentPos(1) - app.robots(idx2).currentPos(1), ...
                            app.robots(idx1).currentPos(2) - app.robots(idx2).currentPos(2));
                
                if dist < PROXIMITY_THRESH
                    % Her iki robot da hareket edemiyor mu?
                    if app.robots(idx1).stuckCount > 20 && app.robots(idx2).stuckCount > 20
                        isDeadlock = true;
                        fprintf('\n🚨 DEADLOCK TESPİT EDİLDİ (Tip 3: Spatial - R%d ve R%d)\n', idx1, idx2);
                        return;
                    end
                end
            end
        end
    end


    function resolveDeadlock(app, activeRobots)

        % ÇOK KATMANLI DEADLOCK ÇÖZÜMÜ
        
        fprintf('⚠️ Deadlock çözülüyor...\n');
        
        % ============================================
        % STRATEJİ 1: ÖNCELİK SİSTEMİ (En küçük ID geri çekilir)
        % ============================================
        priorityRobot = min(activeRobots);
        
        fprintf('   Strateji 1: Robot %d öncelik aldı (geri çekilecek)\n', priorityRobot);
        
        curPos = app.robots(priorityRobot).currentPos;
        
        % Geri çekilme: trajectory'de geriye git
        if size(app.robots(priorityRobot).trajectory, 1) > 5
            backSteps = min(4, size(app.robots(priorityRobot).trajectory, 1) - 1);
            newPos = app.robots(priorityRobot).trajectory(end - backSteps, :);
            
            app.robots(priorityRobot).currentPos = newPos;
            app.robots(priorityRobot).trajectory(end+1,:) = newPos;
            app.robots(priorityRobot).stuckCount = 0;
            app.robots(priorityRobot).waitingFor = [];
            
            fprintf('      R%d (%.1f, %.1f) → (%.1f, %.1f) geri çekildi\n', ...
                priorityRobot, curPos(1), curPos(2), newPos(1), newPos(2));
            
            pause(0.5);
            return;
        end
        
        % ============================================
        % STRATEJİ 2: RASTGELE YAN HAREKET
        % ============================================
        fprintf('   Strateji 2: Rastgele kaçınma hareketleri\n');
        
        for i = activeRobots
            if app.robots(i).stuckCount > 30
                angle = rand * 2 * pi;
                offset = [cos(angle), sin(angle)] * 2.5;
                newPos = app.robots(i).currentPos + offset;
                
                if checkOccupancy(app.map, newPos) < 0.5
                    app.robots(i).currentPos = newPos;
                    app.robots(i).trajectory(end+1,:) = newPos;
                    app.robots(i).stuckCount = 0;
                    fprintf('      R%d yana kaydı\n', i);
                end
            end
        end
        
        pause(0.5);
        
        % ============================================
        % STRATEJİ 3: YENİDEN PLANLAMA (Son çare)
        % ============================================
            fprintf('   Strateji 3: Çok takılan robotlar için yeniden planlama\n');
            
            for i = activeRobots
                if app.robots(i).stuckCount > 60
                    app.replanPath(i);
                end
            end
        end
    
        % ============================================
        % YARDIMCI FONKSİYON: Yol Yeniden Planlama
        % ============================================
        function replanPath(app, robotIdx)
            fprintf('      🔄 R%d için yol yeniden planlanıyor...\n', robotIdx);
            
            curPos = app.robots(robotIdx).currentPos;
            goalPos = app.robots(robotIdx).goal;
            
            plannerType = app.currentPlannerType;
            
            % Yeni yol hesapla
            if strcmp(plannerType, 'Theta*')
                newPath = app.thetaStarPlanner(app.map, curPos, goalPos);
            elseif strcmp(plannerType, 'A*')
                newPath = app.aStarPlanner(app.map, curPos, goalPos);
            else
                newPath = app.prmPlanner(app.map, curPos, goalPos);
            end
            
            if ~isempty(newPath)
                app.robots(robotIdx).path = newPath;
                app.robots(robotIdx).pathIndex = 1;
                app.robots(robotIdx).stuckCount = 0;
                fprintf('      ✅ R%d için yeni yol bulundu (%d waypoints)\n', robotIdx, size(newPath, 1));
            else
                fprintf('      ❌ R%d için yol bulunamadı, hedefe direkt gidiyor\n', robotIdx);
                app.robots(robotIdx).path = [curPos; goalPos];
                app.robots(robotIdx).pathIndex = 1;
            end
        end


    % ============================================
    % LOCAL AVOIDANCE (GOAL-BIASED)
    % ============================================
    function [success, newPos] = localAvoidStepGoalBiased(app, map, curPos, goalPos, stepDist)
        success = false;
        newPos = curPos;

        goalDir = goalPos - curPos;
        if norm(goalDir) < 1e-6
            return;
        end
        goalDir = goalDir / norm(goalDir);
        baseAngle = atan2(goalDir(2), goalDir(1));

        % Try straight first
        candidate = curPos + goalDir * stepDist;
        if checkOccupancy(map, candidate) < 0.5 && app.isSegmentFree(map, curPos, candidate)
            success = true;
            newPos = candidate;
            return;
        end

        % Angular search
        angles = [-90, -60, -45, -30, -15, 15, 30, 45, 60, 90, -120, 120];
        bestScore = inf;
        bestPos = curPos;

        for angleOffset = angles
            theta = baseAngle + deg2rad(angleOffset);
            stepVec = [cos(theta), sin(theta)] * stepDist;
            candidate = curPos + stepVec;

            if checkOccupancy(map, candidate) >= 0.5
                continue;
            end
            if ~app.isSegmentFree(map, curPos, candidate)
                continue;
            end

            score = norm(goalPos - candidate);

            if score < bestScore
                bestScore = score;
                bestPos = candidate;
                success = true;
            end
        end

        newPos = bestPos;
    end

    % ============================================
    % SEGMENT COLLISION CHECK
    % ============================================
    function ok = isSegmentFree(~, map, p1, p2)
        dist = hypot(p2(1) - p1(1), p2(2) - p1(2));
        step = 0.2;
        n = max(ceil(dist / step), 2);

        t = linspace(0, 1, n)';
        samples = p1 + t .* (p2 - p1);

        occ = checkOccupancy(map, samples);
        ok = all(occ < 0.5);
    end


    % ============================================
    % 360° LOCAL AVOIDANCE (Robot-aware)
    % ============================================
    function [success, newPos] = localAvoidStep360(app, map, curPos, goalPos, stepDist, robots, myIdx)
        success = false;
        newPos = curPos;
        
        goalDir = goalPos - curPos;
        if norm(goalDir) < 1e-6
            return;
        end
        goalDir = goalDir / norm(goalDir);
        baseAngle = atan2(goalDir(2), goalDir(1));
        
        % 360° tarama
        angles = -180:15:180;
        bestScore = inf;
        bestPos = curPos;
        
        for angleOffset = angles
            theta = baseAngle + deg2rad(angleOffset);
            stepVec = [cos(theta), sin(theta)] * stepDist;
            candidate = curPos + stepVec;
            
            % Engel kontrolü
            if checkOccupancy(map, candidate) >= 0.5
                continue;
            end
            if ~app.isSegmentFree(map, curPos, candidate)
                continue;
            end
            
            % Diğer robotlarla mesafe kontrolü
            tooClose = false;
            for j = 1:length(robots)
                if j ~= myIdx && ~robots(j).finished
                    if norm(candidate - robots(j).currentPos) < 1.2
                        tooClose = true;
                        break;
                    end
                end
            end
            
            if tooClose
                continue;
            end
            
            % Hedefe olan mesafe skoru
            score = norm(goalPos - candidate);
            
            % Bonus: Hedefe yaklaşan yönü tercih et
            if abs(angleOffset) < 90
                score = score * 0.8;
            end
            
            if score < bestScore
                bestScore = score;
                bestPos = candidate;
                success = true;
            end
        end
        
        newPos = bestPos;
    end


    % ============================================
    % PRM PLANNER
    % ============================================
     function path = prmPlanner(app, map, startWorld, goalWorld)
    
        prm = mobileRobotPRM(map);
    
        % ✅ YOĞUN HARİTA İÇİN GÜVENLİ AYARLAR
        prm.NumNodes = 800;          % Yoğun harita için şart
        prm.ConnectionDistance = 6;   % Çok büyük olursa duvarı deler
    
        % Start / Goal güvenlik kontrolü
        if checkOccupancy(map, startWorld) > 0.5 || ...
           checkOccupancy(map, goalWorld) > 0.5
            path = [];
            return;
        end
    
        path = findpath(prm, startWorld, goalWorld);
    
        if isempty(path)
            path = [];
        end
     end

    % ============================================================
    % THETA* PLANNER (methods (Access = private) bloğunun sonuna ekleyin)
    % ============================================================
    
    function path = thetaStarPlanner(app, map, startWorld, goalWorld)
        % THETA* - A* gibi ama "line of sight" kontrolü ile köşe kesme
        
        startGrid = world2grid(map, startWorld);
        goalGrid = world2grid(map, goalWorld);
        
        occupancyMatrix = getOccupancy(map);
        [rows, cols] = size(occupancyMatrix);
        
        % Sınır kontrolü
        if startGrid(1) < 1 || startGrid(1) > cols || startGrid(2) < 1 || startGrid(2) > rows || ...
           goalGrid(1) < 1 || goalGrid(1) > cols || goalGrid(2) < 1 || goalGrid(2) > rows
            path = [];
            return;
        end
        
        % Engel kontrolü
        if occupancyMatrix(startGrid(2), startGrid(1)) > 0.5 || ...
           occupancyMatrix(goalGrid(2), goalGrid(1)) > 0.5
            path = [];
            return;
        end
        
        % Veri yapıları
        openSet = [startGrid(1), startGrid(2), 0, app.heuristic(startGrid, goalGrid)];
        closedSet = false(rows, cols);
        gScore = inf(rows, cols);
        gScore(startGrid(2), startGrid(1)) = 0;
        parent = zeros(rows, cols, 2);
        parent(startGrid(2), startGrid(1), :) = startGrid;
        
        directions = [1,0; -1,0; 0,1; 0,-1; 1,1; -1,1; 1,-1; -1,-1];
        moveCost = [1, 1, 1, 1, sqrt(2), sqrt(2), sqrt(2), sqrt(2)];
        
        while size(openSet, 1) > 0
            [~, idx] = min(openSet(:, 3) + openSet(:, 4));
            current = openSet(idx, 1:2);
            
            if current(1) == goalGrid(1) && current(2) == goalGrid(2)
                path = app.reconstructPath(parent, current, startGrid, map);
                return;
            end
            
            openSet(idx, :) = [];
            closedSet(current(2), current(1)) = true;
            
            % ✅ THETA* FARKI: Parent'ın parent'ını kontrol et
            currentParent = squeeze(parent(current(2), current(1), :))';
            
            for d = 1:size(directions, 1)
                neighbor = current + directions(d, :);
                
                if neighbor(1) < 1 || neighbor(1) > cols || neighbor(2) < 1 || neighbor(2) > rows
                    continue;
                end
                
                if occupancyMatrix(neighbor(2), neighbor(1)) > 0.5
                    continue;
                end
                
                % Köşe kesme önleme
                if abs(directions(d,1)) == 1 && abs(directions(d,2)) == 1
                    n1 = current + [directions(d,1), 0];
                    n2 = current + [0, directions(d,2)];
                    if occupancyMatrix(n1(2), n1(1)) > 0.5 || occupancyMatrix(n2(2), n2(1)) > 0.5
                        continue;
                    end
                end
                
                if closedSet(neighbor(2), neighbor(1))
                    continue;
                end
                
                % ✅ THETA* ÖZELLİĞİ: Line-of-sight kontrolü
                if app.hasLineOfSight(map, currentParent, neighbor)
                    % Direkt parent'ın parent'ına bağlan
                    distToParentParent = hypot(neighbor(1) - currentParent(1), ...
                                               neighbor(2) - currentParent(2));
                    tentative_gScore = gScore(currentParent(2), currentParent(1)) + distToParentParent;
                    
                    if tentative_gScore < gScore(neighbor(2), neighbor(1))
                        parent(neighbor(2), neighbor(1), :) = currentParent;
                        gScore(neighbor(2), neighbor(1)) = tentative_gScore;
                        fScore = tentative_gScore + app.heuristic(neighbor, goalGrid);
                        
                        % openSet güncelle
                        inOpen = false;
                        for k = 1:size(openSet, 1)
                            if openSet(k, 1) == neighbor(1) && openSet(k, 2) == neighbor(2)
                                openSet(k, 3:4) = [tentative_gScore, fScore - tentative_gScore];
                                inOpen = true;
                                break;
                            end
                        end
                        
                        if ~inOpen
                            openSet = [openSet; neighbor, tentative_gScore, fScore - tentative_gScore];
                        end
                    end
                else
                    % Normal A* gibi davran
                    tentative_gScore = gScore(current(2), current(1)) + moveCost(d);
                    
                    if tentative_gScore < gScore(neighbor(2), neighbor(1))
                        parent(neighbor(2), neighbor(1), :) = current;
                        gScore(neighbor(2), neighbor(1)) = tentative_gScore;
                        fScore = tentative_gScore + app.heuristic(neighbor, goalGrid);
                        
                        inOpen = false;
                        for k = 1:size(openSet, 1)
                            if openSet(k, 1) == neighbor(1) && openSet(k, 2) == neighbor(2)
                                openSet(k, 3:4) = [tentative_gScore, fScore - tentative_gScore];
                                inOpen = true;
                                break;
                            end
                        end
                        
                        if ~inOpen
                            openSet = [openSet; neighbor, tentative_gScore, fScore - tentative_gScore];
                        end
                    end
                end
            end
        end
        
        path = [];
    end
    
    % ============================================================
    % YARDIMCI FONKSİYON 1: Line-of-sight kontrolü
    % ============================================================
    function hasLOS = hasLineOfSight(~, map, p1, p2)
        % Bresenham çizgi algoritması ile engel kontrolü
        
        x0 = round(p1(1)); 
        y0 = round(p1(2));
        x1 = round(p2(1)); 
        y1 = round(p2(2));
        
        dx = abs(x1 - x0);
        dy = abs(y1 - y0);
        
        if x0 < x1
            sx = 1;
        else
            sx = -1;
        end
        
        if y0 < y1
            sy = 1;
        else
            sy = -1;
        end
        
        err = dx - dy;
        
        occupancyMatrix = getOccupancy(map);
        [rows, cols] = size(occupancyMatrix);
        
        while true
            % Sınır kontrolü
            if x0 < 1 || x0 > cols || y0 < 1 || y0 > rows
                hasLOS = false;
                return;
            end
            
            % Engel kontrolü
            if occupancyMatrix(y0, x0) > 0.5
                hasLOS = false;
                return;
            end
            
            if x0 == x1 && y0 == y1
                break;
            end
            
            e2 = 2 * err;
            
            if e2 > -dy
                err = err - dy;
                x0 = x0 + sx;
            end
            
            if e2 < dx
                err = err + dx;
                y0 = y0 + sy;
            end
        end
        
        hasLOS = true;
    end
    
    % ============================================================
    % YARDIMCI FONKSİYON 2: Deadlock için döngü arama
    % ============================================================
    function hasCycle = findWaitCycle(app, startIdx, activeRobots)
        visited = [];
        current = startIdx;
        
        while true
            if ismember(current, visited)
                % Döngü bulundu!
                fprintf('   Döngü zinciri: ');
                for j = visited
                    fprintf('R%d → ', j);
                end
                fprintf('R%d\n', current);
                hasCycle = true;
                return;
            end
            
            visited(end+1) = current; %#ok<AGROW>
            
            if isempty(app.robots(current).waitingFor)
                break;
            end
            
            next = app.robots(current).waitingFor(1);
            
            if next < 1 || next > length(app.robots) || ~ismember(next, activeRobots)
                break;
            end
            
            current = next;
        end
        
        hasCycle = false;
    end


    % ============================================
    % A* PLANNER
    % ============================================
    function path = aStarPlanner(app, map, startWorld, goalWorld)
        startGrid = world2grid(map, startWorld);
        goalGrid = world2grid(map, goalWorld);
        
        occupancyMatrix = getOccupancy(map);
        [rows, cols] = size(occupancyMatrix);
        
        if startGrid(1) < 1 || startGrid(1) > cols || startGrid(2) < 1 || startGrid(2) > rows || ...
           goalGrid(1) < 1 || goalGrid(1) > cols || goalGrid(2) < 1 || goalGrid(2) > rows
            path = [];
            return;
        end
        
        if occupancyMatrix(startGrid(2), startGrid(1)) > 0.5 || ...
           occupancyMatrix(goalGrid(2), goalGrid(1)) > 0.5
            path = [];
            return;
        end
        
        openSet = [startGrid(1), startGrid(2), 0, app.heuristic(startGrid, goalGrid)];
        closedSet = false(rows, cols);
        gScore = inf(rows, cols);
        gScore(startGrid(2), startGrid(1)) = 0;
        cameFrom = zeros(rows, cols, 2);
        
        directions = [1,0; -1,0; 0,1; 0,-1; 1,1; -1,1; 1,-1; -1,-1];
        moveCost = [1, 1, 1, 1, sqrt(2), sqrt(2), sqrt(2), sqrt(2)];
        
        while size(openSet, 1) > 0
            [~, idx] = min(openSet(:, 3) + openSet(:, 4));
            current = openSet(idx, 1:2);
            
            if current(1) == goalGrid(1) && current(2) == goalGrid(2)
                path = app.reconstructPath(cameFrom, current, startGrid, map);
                return;
            end
            
            openSet(idx, :) = [];
            closedSet(current(2), current(1)) = true;
            
            for d = 1:size(directions, 1)
                neighbor = current + directions(d, :);
                
                if neighbor(1) < 1 || neighbor(1) > cols || neighbor(2) < 1 || neighbor(2) > rows
                    continue;
                end
                
                if occupancyMatrix(neighbor(2), neighbor(1)) > 0.5
                    continue;
                end

                % Corner cutting prevention
                if abs(directions(d,1)) == 1 && abs(directions(d,2)) == 1
                    n1 = current + [directions(d,1), 0];
                    n2 = current + [0, directions(d,2)];
                    if occupancyMatrix(n1(2), n1(1)) > 0.5 || occupancyMatrix(n2(2), n2(1)) > 0.5
                        continue;
                    end
                end

                if closedSet(neighbor(2), neighbor(1))
                    continue;
                end
                
                tentative_gScore = gScore(current(2), current(1)) + moveCost(d);
                
                if tentative_gScore < gScore(neighbor(2), neighbor(1))
                    cameFrom(neighbor(2), neighbor(1), :) = current;
                    gScore(neighbor(2), neighbor(1)) = tentative_gScore;
                    fScore = tentative_gScore + app.heuristic(neighbor, goalGrid);
                    
                    inOpen = false;
                    for k = 1:size(openSet, 1)
                        if openSet(k, 1) == neighbor(1) && openSet(k, 2) == neighbor(2)
                            openSet(k, 3:4) = [tentative_gScore, fScore - tentative_gScore];
                            inOpen = true;
                            break;
                        end
                    end
                    
                    if ~inOpen
                        openSet = [openSet; neighbor, tentative_gScore, fScore - tentative_gScore];
                    end
                end


            end 
        end 
        path = [];
        end

        % Button pushed function: CakismasizcokrobotButton
        function CakismasizcokrobotButtonPushed(app, event)

            fprintf('\n========================================\n');
            fprintf('TEST SENARYO 1: ÇAKIŞMASIZ ÇOK ROBOT\n');
            fprintf('========================================\n');
            
            app.lastSeed = 123456;
            rng(app.lastSeed);
            app.scenarioName = 'Test 1: Çakişmasiz';
            
            % ✅ Basit harita - Sadece ortada birkaç engel
            map = binaryOccupancyMap(app.mapSize(1), app.mapSize(2));
            
            % Ortada 2x2 engeller (robotlar bunların etrafından geçecek)
            obstacles = [
                20, 20, 2, 2;
                30, 30, 2, 2
            ];
            
            for k = 1:size(obstacles, 1)
                x = obstacles(k, 1);
                y = obstacles(k, 2);
                w = obstacles(k, 3);
                h = obstacles(k, 4);
                
                [X, Y] = meshgrid(x:min(x+w-1, app.mapSize(1)), ...
                                  y:min(y+h-1, app.mapSize(2)));
                setOccupancy(map, [X(:), Y(:)], 1);
            end
            
            app.map = map;
            
            show(map, 'Parent', app.UIAxes);
            xlim(app.UIAxes, [0 app.mapSize(1)])
            ylim(app.UIAxes, [0 app.mapSize(2)])
            axis(app.UIAxes, 'equal')
            title(app.UIAxes, 'Test 1: Çakışmasız Senaryo');
            drawnow;
            
            % ✅ 4 ROBOT - HER BİRİ FARKLI ÇEYREKTEKİ KÖŞE ROTASI
            app.numRobots = 4;
            app.RobotSayisiSpinner.Value = 4;
            app.robots = struct([]);
            
            % ✅ HER ROBOT KENDI ÇEYREĞİNDE KALACAK
            robotPositions = [
                % [start_x, start_y, goal_x, goal_y]
                5,   5,   15,  15;   % R1: Sol alt köşe → Sol alt iç (küçük hareket)
                45,  5,   35,  15;   % R2: Sağ alt köşe → Sağ alt iç
                5,   45,  15,  35;   % R3: Sol üst köşe → Sol üst iç
                45,  45,  35,  35    % R4: Sağ üst köşe → Sağ üst iç
            ];
            
            for i = 1:app.numRobots
                app.robots(i).start = [robotPositions(i,1), robotPositions(i,2)];
                app.robots(i).goal  = [robotPositions(i,3), robotPositions(i,4)];
                app.robots(i).currentPos = app.robots(i).start;
                app.robots(i).path = [];
                app.robots(i).pathIndex = 1;
                app.robots(i).finished = false;
                app.robots(i).trajectory = app.robots(i).start;
                app.robots(i).theta = atan2(app.robots(i).goal(2)-app.robots(i).start(2), ...
                                             app.robots(i).goal(1)-app.robots(i).start(1));
                app.robots(i).waitingFor = [];
                app.robots(i).stuckCount = 0;
                app.robots(i).velLinear  = zeros(0,1);
                app.robots(i).velAngular = zeros(0,1);
                app.robots(i).wheelL     = zeros(0,1);
                app.robots(i).wheelR     = zeros(0,1);
            end
            
            % Görselleştir
            hold(app.UIAxes, 'on');
            colors = lines(4);
            
            for i = 1:app.numRobots
                % Başlangıç
                plot(app.UIAxes, app.robots(i).start(1), app.robots(i).start(2), ...
                    'o', 'MarkerSize', 10, 'MarkerFaceColor', colors(i,:), ...
                    'MarkerEdgeColor', 'k', 'LineWidth', 2);
                
                % Hedef
                plot(app.UIAxes, app.robots(i).goal(1), app.robots(i).goal(2), ...
                    'x', 'MarkerSize', 12, 'LineWidth', 3, 'Color', colors(i,:));
                
                % Robot numarası
                text(app.UIAxes, app.robots(i).start(1)+0.8, app.robots(i).start(2)+0.8, ...
                    sprintf('%d', i), 'Color', 'w', 'FontSize', 9, 'FontWeight', 'bold', ...
                    'BackgroundColor', 'k', 'Margin', 1);
                
                % Beklenen yol çizgisi (düz çizgi)
                plot(app.UIAxes, ...
                    [app.robots(i).start(1), app.robots(i).goal(1)], ...
                    [app.robots(i).start(2), app.robots(i).goal(2)], ...
                    '--', 'Color', colors(i,:), 'LineWidth', 1.5, 'LineStyle', ':');
            end
            
            hold(app.UIAxes, 'off');
            
            fprintf('✅ Senaryo hazır!\n');
            fprintf('   R1: Sol alt çeyrek (5,5) → (15,15)\n');
            fprintf('   R2: Sağ alt çeyrek (45,5) → (35,15)\n');
            fprintf('   R3: Sol üst çeyrek (5,45) → (15,35)\n');
            fprintf('   R4: Sağ üst çeyrek (45,45) → (35,35)\n');
            fprintf('   ℹ️ Her robot kendi çeyreğinde kalıyor\n');
        end

        % Button pushed function: CakismaaynihucreButton
        function CakismaaynihucreButtonPushed(app, event)
            fprintf('\n========================================\n');
            fprintf('TEST SENARYO 2: AYNI HÜCRE ÇAKIŞMASI\n');
            fprintf('========================================\n');
            
            app.lastSeed = 234567;
            rng(app.lastSeed);
            app.scenarioName = 'Test 2: Aynı Hücre Çakışması';
            
            % Basit harita
            map = binaryOccupancyMap(app.mapSize(1), app.mapSize(2));
            
            % Ortada tek bir engel
            [X, Y] = meshgrid(23:27, 23:27);
            setOccupancy(map, [X(:), Y(:)], 1);
            
            app.map = map;
            
            show(map, 'Parent', app.UIAxes);
            xlim(app.UIAxes, [0 app.mapSize(1)])
            ylim(app.UIAxes, [0 app.mapSize(2)])
            axis(app.UIAxes, 'equal')
            title(app.UIAxes, 'Test 2: Aynı Hücre Çakışması');
            drawnow;
            
            % 3 robot - hepsi aynı hedefe gidecek
            app.numRobots = 3;
            app.RobotSayisiSpinner.Value = 3;
            app.robots = struct([]);
            
            % 3 robot farklı köşelerden aynı noktaya
            commonGoal = [25, 45];  % Ortak hedef
            
            robotStarts = [
                10, 10;   % Sol alt
                40, 10;   % Sağ alt
                25, 10    % Alt orta
            ];
            
            for i = 1:app.numRobots
                app.robots(i).start = robotStarts(i,:);
                app.robots(i).goal  = commonGoal;
                app.robots(i).currentPos = app.robots(i).start;
                app.robots(i).path = [];
                app.robots(i).pathIndex = 1;
                app.robots(i).finished = false;
                app.robots(i).trajectory = app.robots(i).start;
                app.robots(i).theta = atan2(app.robots(i).goal(2)-app.robots(i).start(2), ...
                                             app.robots(i).goal(1)-app.robots(i).start(1));
                app.robots(i).waitingFor = [];
                app.robots(i).stuckCount = 0;
                app.robots(i).velLinear  = zeros(0,1);
                app.robots(i).velAngular = zeros(0,1);
                app.robots(i).wheelL     = zeros(0,1);
                app.robots(i).wheelR     = zeros(0,1);
            end
            
            % Görselleştir
            hold(app.UIAxes, 'on');
            for i = 1:app.numRobots
                plot(app.UIAxes, app.robots(i).start(1), app.robots(i).start(2), ...
                    'go', 'MarkerSize', 8, 'MarkerFaceColor', 'g');
                text(app.UIAxes, app.robots(i).start(1)+0.5, app.robots(i).start(2)+0.5, ...
                    sprintf('%d', i), 'Color', 'w', 'FontSize', 8, 'FontWeight', 'bold');
            end
            % Ortak hedef
            plot(app.UIAxes, commonGoal(1), commonGoal(2), ...
                'rx', 'MarkerSize', 12, 'LineWidth', 3);
            text(app.UIAxes, commonGoal(1)+1, commonGoal(2)+1, ...
                'ORTAK HEDEF', 'Color', 'r', 'FontSize', 10, 'FontWeight', 'bold');
            hold(app.UIAxes, 'off');
            
            fprintf('✅ Senaryo hazır! Robotlar aynı hedefe gidecek.\n');
        end

        % Button pushed function: CakismakarsilikligecisButton
        function CakismakarsilikligecisButtonPushed(app, event)
                fprintf('\n========================================\n');
                fprintf('TEST SENARYO 3: KARŞILIKLI GEÇİŞ\n');
                fprintf('========================================\n');
                
                app.lastSeed = 345678;
                rng(app.lastSeed);
                app.scenarioName = 'Test 3: Karşılıklı Geçiş';
                
                % Dar koridor haritası
                map = binaryOccupancyMap(app.mapSize(1), app.mapSize(2));
                
                % Dar koridor oluştur
                [X, Y] = meshgrid(20:21, 1:50);
                setOccupancy(map, [X(:), Y(:)], 1);
                
                [X, Y] = meshgrid(28:29, 1:50);
                setOccupancy(map, [X(:), Y(:)], 1);
                
                % Ortada dar geçit aç
                [X, Y] = meshgrid(20:29, 24:26);
                setOccupancy(map, [X(:), Y(:)], 0);
                
                app.map = map;
                
                show(map, 'Parent', app.UIAxes);
                xlim(app.UIAxes, [0 app.mapSize(1)])
                ylim(app.UIAxes, [0 app.mapSize(2)])
                axis(app.UIAxes, 'equal')
                title(app.UIAxes, 'Test 3: Karşılıklı Geçiş (Dar Koridor)');
                drawnow;
                
                % 2 robot - karşılıklı geçiş
                app.numRobots = 2;
                app.RobotSayisiSpinner.Value = 2;
                app.robots = struct([]);
                
                % Robot 1: Sol → Sağ
                app.robots(1).start = [10, 25];
                app.robots(1).goal  = [40, 25];
                
                % Robot 2: Sağ → Sol
                app.robots(2).start = [40, 25];
                app.robots(2).goal  = [10, 25];
                
                for i = 1:app.numRobots
                    app.robots(i).currentPos = app.robots(i).start;
                    app.robots(i).path = [];
                    app.robots(i).pathIndex = 1;
                    app.robots(i).finished = false;
                    app.robots(i).trajectory = app.robots(i).start;
                    app.robots(i).theta = atan2(app.robots(i).goal(2)-app.robots(i).start(2), ...
                                                 app.robots(i).goal(1)-app.robots(i).start(1));
                    app.robots(i).waitingFor = [];
                    app.robots(i).stuckCount = 0;
                    app.robots(i).velLinear  = zeros(0,1);
                    app.robots(i).velAngular = zeros(0,1);
                    app.robots(i).wheelL     = zeros(0,1);
                    app.robots(i).wheelR     = zeros(0,1);
                end
                
                % Görselleştir
                hold(app.UIAxes, 'on');
                app.robotColors = ['g', 'b'];
                for i = 1:app.numRobots
                    plot(app.UIAxes, app.robots(i).start(1), app.robots(i).start(2), ...
                        'o', 'MarkerSize', 8, 'MarkerFaceColor', app.robotColors(i), 'MarkerEdgeColor', app.robotColors(i));
                    plot(app.UIAxes, app.robots(i).goal(1), app.robots(i).goal(2), ...
                        'x', 'MarkerSize', 10, 'LineWidth', 2, 'Color', app.robotColors(i));
                    
                    % Ok çiz (yön göster)
                    quiver(app.UIAxes, app.robots(i).start(1), app.robots(i).start(2), ...
                        (app.robots(i).goal(1)-app.robots(i).start(1))*0.3, 0, ...
                        0, 'Color', app.robotColors(i), 'LineWidth', 2, 'MaxHeadSize', 2);
                    
                    text(app.UIAxes, app.robots(i).start(1)+0.5, app.robots(i).start(2)+1.5, ...
                        sprintf('R%d', i), 'Color', 'w', 'FontSize', 8, 'FontWeight', 'bold');
                end
                hold(app.UIAxes, 'off');
                
                fprintf('✅ Senaryo hazır! Robotlar dar koridorda karşılaşacak.\n');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'sky');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Robotlar ve Yolları')
            xlabel(app.UIAxes, 'X [meters]')
            ylabel(app.UIAxes, 'Y [meters]')
            zlabel(app.UIAxes, 'Z')
            app.RandomMapButton = uibutton(app.GridLayout, 'push');
            app.RandomMapButton.ButtonPushedFcn = createCallbackFcn(app, @RandomMapButtonPushed, true);
            app.RandomMapButton.Text = 'Random Map';
            app.DarKoridorButton = uibutton(app.GridLayout, 'push');
            app.DarKoridorButton.ButtonPushedFcn = createCallbackFcn(app, @DarKoridorButtonPushed, true);
            app.DarKoridorButton.Text = 'Dar Koridor';
            app.RobotlarYerletirButton = uibutton(app.GridLayout, 'push');
            app.RobotlarYerletirButton.ButtonPushedFcn = createCallbackFcn(app, @RobotlarYerletirButtonPushed, true);
            app.RobotlarYerletirButton.Text = 'Robotları Yerleştir';
            app.DropPlannerDropDown = uidropdown(app.GridLayout);
            app.DropPlannerDropDown.Items = {'A*', 'PRM', 'Theta*'};
            app.DropPlannerDropDown.Value = 'Theta*';
            app.SimulasyonubaslatButton = uibutton(app.GridLayout, 'push');
            app.SimulasyonubaslatButton.ButtonPushedFcn = createCallbackFcn(app, @SimulasyonubaslatButtonPushed, true);
            app.SimulasyonubaslatButton.Text = 'Simulasyonu baslat';
            app.DropPlannerDropDownLabel = uilabel(app.GridLayout);
            app.DropPlannerDropDownLabel.Text = 'Drop Planner';
            app.RobotSayisiSpinnerLabel = uilabel(app.GridLayout);
            app.RobotSayisiSpinnerLabel.Text = 'Robot Sayisi';
            app.RobotSayisiSpinner = uispinner(app.GridLayout);
            app.RobotSayisiSpinner.Limits = [1 20];
            app.RobotSayisiSpinner.Value = 10;
            app.CakismasizcokrobotButton = uibutton(app.GridLayout, 'push');
            app.CakismasizcokrobotButton.ButtonPushedFcn = createCallbackFcn(app, @CakismasizcokrobotButtonPushed, true);
            app.CakismasizcokrobotButton.Text = 'Cakismasiz(cok robot)';
            app.CakismaaynihucreButton = uibutton(app.GridLayout, 'push');
            app.CakismaaynihucreButton.ButtonPushedFcn = createCallbackFcn(app, @CakismaaynihucreButtonPushed, true);
            app.CakismaaynihucreButton.Text = 'Cakisma(ayni hucre)';
            app.CakismakarsilikligecisButton = uibutton(app.GridLayout, 'push');
            app.CakismakarsilikligecisButton.ButtonPushedFcn = createCallbackFcn(app, @CakismakarsilikligecisButtonPushed, true);
            app.CakismakarsilikligecisButton.Text = 'Cakisma (karsilikli gecis)';
            app.ZoomButton = uibutton(app.GridLayout, 'push');
            app.ZoomButton.Text = 'Zoom';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end